classdef HarmonyGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button_help         matlab.ui.control.Button
        Button_tran         matlab.ui.control.Button
        TextArea_chordName  matlab.ui.control.TextArea
        Label_3             matlab.ui.control.Label
        DropDown_tonality   matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        Label_state         matlab.ui.control.Label
        Button_2            matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        Label               matlab.ui.control.Label
        Button              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             txt=app.TextArea.Value;
%             str=cell2mat(txt);
%             key=ones(1,length(str));
%             for i=1:length(str)
%                 key(i)=str(i)-'0';
%             end
%             tonality=app.DropDown_tonality.Value;
%             music=generateMusic(key,tonality);
            
            music=generateMusicFromName(app.TextArea_chordName.Value);
            sound(music,48000);
            app.Label_state.Text="播放";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            clear sound;
            app.Label_state.Text="空闲";
        end

        % Button pushed function: Button_tran
        function Button_tranPushed(app, event)
            str=cell2mat(app.TextArea.Value);
            tonality=app.DropDown_tonality.Value;
            pgs=getProgression(str,tonality);
            app.TextArea_chordName.Value=pgs;
        end

        % Button pushed function: Button_help
        function Button_helpPushed(app, event)
            %web("HarmonyGenHelp.html",'-browser');
            system("HarmonyGenHelp.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '播放';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '和弦级数走向';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'15634125'};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '停止';
            app.Label_state = uilabel(app.UIFigure);
            app.Label_state.Text = '空闲';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '调性';
            app.DropDown_tonality = uidropdown(app.UIFigure);
            app.DropDown_tonality.Items = {'C', '#C', 'D', 'bE', 'E', 'F', '#F', 'G', '#G', 'A', 'bB', 'B', 'a', 'bb', 'b', 'c', '#c', 'd', 'be', 'e', 'f', '#f', 'g', '#g'};
            app.DropDown_tonality.Value = 'C';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '和弦记号';
            app.TextArea_chordName = uitextarea(app.UIFigure);
            app.Button_tran = uibutton(app.UIFigure, 'push');
            app.Button_tran.ButtonPushedFcn = createCallbackFcn(app, @Button_tranPushed, true);
            app.Button_tran.Text = '转换↓';
            app.Button_help = uibutton(app.UIFigure, 'push');
            app.Button_help.ButtonPushedFcn = createCallbackFcn(app, @Button_helpPushed, true);
            app.Button_help.Text = '帮助';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarmonyGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end